classdef CompVision_PartB < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        btn_trainingSet    matlab.ui.control.Button
        btn_testImage      matlab.ui.control.Button
        btn_classifyImage  matlab.ui.control.Button
        lbl_status         matlab.ui.control.Label
    end


    properties (Access = private)
        srcTrainingImages;
        testImage;
    end


    methods (Access = private)

        % Button pushed function: btn_classifyImage
        function btn_classifyImageButtonPushed(app, event)
            
            app.lbl_status.Text = 'Working ...';
            
            imageLabel = predictImageLabel(app.testImage, app.srcTrainingImages);
            
            app.lbl_status.Text = strcat( 'Prediction: ', imageLabel);
            
        end

        % Button pushed function: btn_trainingSet
        function btn_trainingSetButtonPushed(app, event)
            app.srcTrainingImages = uigetdir;
        end

        % Button pushed function: btn_testImage
        function btn_testImageButtonPushed(app, event)
            [filename, pathname] = uigetfile({'*.jpg';'*.png';'*.bmp';'*.*'},'Pick a file');
            app.testImage = imread(strcat(pathname, filename));
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.btn_trainingSet = uibutton(app.UIFigure, 'push');
            app.btn_trainingSet.ButtonPushedFcn = createCallbackFcn(app, @btn_trainingSetButtonPushed, true);
            app.btn_trainingSet.Text = 'Select Training Set';
            app.btn_testImage = uibutton(app.UIFigure, 'push');
            app.btn_testImage.ButtonPushedFcn = createCallbackFcn(app, @btn_testImageButtonPushed, true);
            app.btn_testImage.Text = 'Select Image To Test';
            app.btn_classifyImage = uibutton(app.UIFigure, 'push');
            app.btn_classifyImage.ButtonPushedFcn = createCallbackFcn(app, @btn_classifyImageButtonPushed, true);
            app.btn_classifyImage.Text = 'Classify Image';
            app.lbl_status = uilabel(app.UIFigure);
            app.lbl_status.Text = '';
        end
    end

    methods (Access = public)

        % Construct app
        function app = CompVision_PartB

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end