classdef computer_vision < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        CompositeImageCreatorUIFigure  matlab.ui.Figure
        btnTarget                      matlab.ui.control.Button
        btnBrowseLabel                 matlab.ui.control.Label
        XLabel                         matlab.ui.control.Label
        numPixelsX                     matlab.ui.control.NumericEditField
        btnSubmit                      matlab.ui.control.Button
        PleaseselectthetypeofcompositionbuilderDropDownLabel  matlab.ui.control.Label
        compositionBuilder             matlab.ui.control.DropDown
        YLabel                         matlab.ui.control.Label
        numPixelsY                     matlab.ui.control.NumericEditField
        NumberoftilesDropDownLabel     matlab.ui.control.Label
        numTiles                       matlab.ui.control.DropDown
        btnSource                      matlab.ui.control.Button
        PleaseselectyoursourceimagedirectoryLabel  matlab.ui.control.Label
    end

    properties (Access = private)
        
        % Target Image Properties
        targetImage
        targetImageHeight
        targetImageWidth
        
        % Source Image Properties
        srcImageDirectory
        srcImageSet
        
        % Tiling Properties
        tileWidth
        tileHeight
        
        % AVG Grayscale Composition Properties
        srcGrayMeanValues
        
        % AVG RGB Composition Properties
        srcRGBMeanValues
        
        % CCV Composition Properties
        srcCCVValues
        
        % Histogram Properties
        histogramValues
        
        % Final Image Properties
        compositeImage
        compositeImageFun
        
    end

    methods (Access = private)

        % Button pushed function: btnTarget
        function btnTargetPushed(app, event)
            app.targetImage = getTargetImage();
        end

        % Button pushed function: btnSubmit
        function btnSubmitPushed(app, event)
            app.targetImage = resizeTargetImage(app.targetImage, app.numPixelsY.Value, app.numPixelsX.Value);
            
            [app.tileWidth, app.tileHeight] = setTileSizes(app.numTiles.Value, app.numPixelsX.Value, app.numPixelsY.Value);
            
            % Determines how the composite image shall be created
            switch(app.compositionBuilder.Value)
                case 'Avg. RGB Values'
                    app.srcRGBMeanValues = buildCompositeLibraryRGBValues(app.srcImageSet, app.tileWidth, app.tileHeight);
                    
                    app.compositeImageFun = @(block_struct) buildRGBComposite(block_struct.data, app.srcRGBMeanValues, app.srcImageSet, app.tileWidth, app.tileHeight);
                    
                case 'Avg. Grayscale Values'
                    
                case 'Colour Coherence Vector'
                    app.srcCCVValues = buildCompositeLibraryCCVValues(app.srcImageSet, app.tileWidth, app.tileHeight);
                    
                    app.compositeImageFun = @(block_struct) buildCCVComposite(block_struct.data, app.srcCCVValues, app.srcImageSet, app.tileWidth, app.tileHeight);
                    
                case 'Chi-Squared Distance'
                    app.histogramValues = buildCompositeLibraryHistogramValues(app.srcImageSet, app.tileWidth, app.tileHeight);
                    
                    app.compositeImageFun = @(block_struct) buildCDistanceComposite(block_struct.data, app.histogramValues, app.srcImageSet, app.tileWidth, app.tileHeight);
                    
                case 'Euclidean Distance'
                    app.histogramValues = buildCompositeLibraryHistogramValues(app.srcImageSet, app.tileWidth, app.tileHeight);
                    
                    app.compositeImageFun = @(block_struct) buildEDistanceComposite(block_struct.data, app.histogramValues, app.srcImageSet, app.tileWidth, app.tileHeight);
                    
                otherwise
                    fprintf('Error');
            end
            
            app.compositeImage = createCompositeImage(app.targetImage, app.tileWidth, app.tileHeight, app.compositeImageFun);
            
            imshow(app.compositeImage);
        end

        % Button pushed function: btnSource
        function btnSourcePushed(app, event)
            app.srcImageSet = getSourceLibrary();
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.CompositeImageCreatorUIFigure = uifigure;
            app.CompositeImageCreatorUIFigure.Name = 'Composite Image Creator';
            app.btnTarget = uibutton(app.CompositeImageCreatorUIFigure, 'push');
            app.btnTarget.ButtonPushedFcn = createCallbackFcn(app, @btnTargetPushed, true);
            app.btnTarget.Text = 'Browse...';
            app.btnBrowseLabel = uilabel(app.CompositeImageCreatorUIFigure);
            app.btnBrowseLabel.Text = 'Please select your target image:';
            app.XLabel = uilabel(app.CompositeImageCreatorUIFigure);
            app.XLabel.Text = 'X';
            app.numPixelsX = uieditfield(app.CompositeImageCreatorUIFigure, 'numeric');
            app.numPixelsX.Limits = [1 Inf];
            app.numPixelsX.ValueDisplayFormat = '%.0f';
            app.numPixelsX.Value = 1;
            app.btnSubmit = uibutton(app.CompositeImageCreatorUIFigure, 'push');
            app.btnSubmit.ButtonPushedFcn = createCallbackFcn(app, @btnSubmitPushed, true);
            app.btnSubmit.Text = 'Submit';
            app.PleaseselectthetypeofcompositionbuilderDropDownLabel = uilabel(app.CompositeImageCreatorUIFigure);
            app.PleaseselectthetypeofcompositionbuilderDropDownLabel.Text = 'Please select the type of composition builder';
            app.compositionBuilder = uidropdown(app.CompositeImageCreatorUIFigure);
            app.compositionBuilder.Items = {'Avg. RGB Values', 'Avg. Grayscale Values', 'Colour Coherence Vector', 'Chi-Squared Distance', 'Euclidean Distance'};
            app.compositionBuilder.Value = 'Avg. RGB Values';
            app.YLabel = uilabel(app.CompositeImageCreatorUIFigure);
            app.YLabel.Text = 'Y';
            app.numPixelsY = uieditfield(app.CompositeImageCreatorUIFigure, 'numeric');
            app.numPixelsY.Limits = [1 Inf];
            app.numPixelsY.ValueDisplayFormat = '%.0f';
            app.numPixelsY.Value = 1;
            app.NumberoftilesDropDownLabel = uilabel(app.CompositeImageCreatorUIFigure);
            app.NumberoftilesDropDownLabel.Text = 'Number of tiles';
            app.numTiles = uidropdown(app.CompositeImageCreatorUIFigure);
            app.numTiles.Items = {'49', '121', '225', '361', '484', '576', '841', '961', '1156', '1369', '1600', '2025', '2304', '2500', '2809', '3364', '3724', '4489', '4900', '5776', '7225', '10000'};
            app.numTiles.Value = '49';
            app.btnSource = uibutton(app.CompositeImageCreatorUIFigure, 'push');
            app.btnSource.ButtonPushedFcn = createCallbackFcn(app, @btnSourcePushed, true);
            app.btnSource.Text = 'Browse...';
            app.PleaseselectyoursourceimagedirectoryLabel = uilabel(app.CompositeImageCreatorUIFigure);
            app.PleaseselectyoursourceimagedirectoryLabel.Text = 'Please select your source image directory';
        end
    end

    methods (Access = public)

        % Construct app
        function app = computer_vision

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.CompositeImageCreatorUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.CompositeImageCreatorUIFigure)
        end
    end
end